classdef TableauBordApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TableaudeBordLabel              matlab.ui.control.Label
        STOPLamp                        matlab.ui.control.Lamp
        STOPLampLabel                   matlab.ui.control.Label
        MoteurLamp                      matlab.ui.control.Lamp
        MoteurLampLabel                 matlab.ui.control.Label
        CarburantLamp                   matlab.ui.control.Lamp
        CarburantLampLabel              matlab.ui.control.Label
        RadiateurLamp                   matlab.ui.control.Lamp
        RadiateurLampLabel              matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        DmarragedelavoitureSwitch       matlab.ui.control.ToggleSwitch
        DmarragedelavoitureSwitchLabel  matlab.ui.control.Label
        TempratureCGauge                matlab.ui.control.NinetyDegreeGauge
        TempratureCGaugeLabel           matlab.ui.control.Label
        NiveauCarburantGauge            matlab.ui.control.NinetyDegreeGauge
        NiveauCarburantGaugeLabel       matlab.ui.control.Label
        ComptetourRPMGauge              matlab.ui.control.Gauge
        ComptetourRPMGaugeLabel         matlab.ui.control.Label
        VitessekmhGauge                 matlab.ui.control.Gauge
        VitessekmhGaugeLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        rxCh
        db
    end
    
    methods (Access = private)
        
        function traiterMessages(app)
            msgs = receive(app.rxCh, Inf);
            for i = 1:length(msgs)
                msg = msgs(i);
                if strcmp(msg.Name, 'VehiculeData')
                    vitesse   = msg.Signals.VitesseVehicule;
                    carburant = msg.Signals.NiveauCarburant;
                    app.VitessekmhGauge.Value      = vitesse;
                    app.NiveauCarburantGauge.Value = carburant;
                    if carburant < 12.5
                        app.CarburantLamp.Color = 'red';
                    elseif carburant < 25
                        app.CarburantLamp.Color = 'yellow';
                else
                    app.CarburantLamp.Color = [0.9608 0.9608 0.9608];
                    end
                elseif strcmp(msg.Name, 'MoteurData')
                    rpm  = msg.Signals.MoteurRPM;
                    temp = msg.Signals.MoteurTemp;
                    app.ComptetourRPMGauge.Value = rpm;
                    app.TempratureCGauge.Value   = temp;
                    if rpm >= 6000 || temp >= 125
                        app.MoteurLamp.Color = 'red';
                    elseif rpm >= 5500 || temp >= 105
                        app.MoteurLamp.Color = 'yellow';
                    else
                        app.MoteurLamp.Color = [0.9608 0.9608 0.9608];
                    end
                    if temp > 70
                        app.RadiateurLamp.Color = 'yellow';
                    else
                        app.RadiateurLamp.Color = [0.9608 0.9608 0.9608];
                    end
                end
            end
            if isequal(app.MoteurLamp.Color, [1 0 0])
                app.STOPLamp.Color = 'red';
            else
                app.STOPLamp.Color = [0.9608 0.9608 0.9608];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: DmarragedelavoitureSwitch
        function DmarragedelavoitureSwitchValueChanged(app, event)
            value = app.DmarragedelavoitureSwitch.Value;
            if strcmp(value, 'START')
                app.db = canDatabase("tableau_de_bord.dbc");
                app.rxCh = canChannel("MathWorks","Virtual 1",2);
                app.rxCh.Database = app.db;
                app.rxCh.MessageReceivedFcnCount = 1;
                app.rxCh.MessageReceivedFcn = @(src,event) traiterMessages(app);
                start(app.rxCh);
                app.Lamp.Color = 'green';
                if exist('generateMsgsDb', 'file') == 2
                    generateMsgsDb;
                end
            else
                stop(app.rxCh);
                app.rxCh = [];
                app.VitessekmhGauge.Value      = 0;
                app.ComptetourRPMGauge.Value   = 0;
                app.NiveauCarburantGauge.Value = 0;
                app.TempratureCGauge.Value     = -10;
                app.Lamp.Color          = [0.9608 0.9608 0.9608];
                app.STOPLamp.Color      = [0.9608 0.9608 0.9608];
                app.MoteurLamp.Color    = [0.9608 0.9608 0.9608];
                app.CarburantLamp.Color = [0.9608 0.9608 0.9608];
                app.RadiateurLamp.Color = [0.9608 0.9608 0.9608];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VitessekmhGaugeLabel = uilabel(app.UIFigure);
            app.VitessekmhGaugeLabel.Text = 'Vitesse (km/h)';
            app.VitessekmhGauge = uigauge(app.UIFigure, 'circular');
            app.VitessekmhGauge.Limits = [0 240];
            app.VitessekmhGauge.MajorTicks = [0 20 40 60 80 100 120 140 160 180 200 220 240];
            app.VitessekmhGauge.MajorTickLabels = {'0', '20', '40', '60', '80', '100', '120', '140', '160', '180', '200', '220', '240'};
            app.ComptetourRPMGaugeLabel = uilabel(app.UIFigure);
            app.ComptetourRPMGaugeLabel.Text = 'Compte-tour (RPM)';
            app.ComptetourRPMGauge = uigauge(app.UIFigure, 'circular');
            app.ComptetourRPMGauge.Limits = [0 7000];
            app.ComptetourRPMGauge.MajorTicks = [0 1000 2000 3000 4000 5000 6000 7000];
            app.ComptetourRPMGauge.MajorTickLabels = {'0', '1000', '2000', '3000', '4000', '5000', '6000', '7000'};
            app.NiveauCarburantGaugeLabel = uilabel(app.UIFigure);
            app.NiveauCarburantGaugeLabel.Text = 'Niveau Carburant';
            app.NiveauCarburantGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.NiveauCarburantGauge.MinorTicks = [12.5 37.5 62.5 87.5];
            app.NiveauCarburantGauge.MajorTicksMode = 'manual';
            app.TempratureCGaugeLabel = uilabel(app.UIFigure);
            app.TempratureCGaugeLabel.Text = 'Température (°C)';
            app.TempratureCGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.TempratureCGauge.Limits = [-10 150];
            app.TempratureCGauge.MajorTicks = [-10 0 50 100 150];
            app.TempratureCGauge.MajorTickLabels = {'-10', '0', '50', '100', '150'};
            app.TempratureCGauge.MinorTicks = [25 75 125];
            app.DmarragedelavoitureSwitchLabel = uilabel(app.UIFigure);
            app.DmarragedelavoitureSwitchLabel.Text = 'Démarrage de la voiture';
            app.DmarragedelavoitureSwitch = uiswitch(app.UIFigure, 'toggle');
            app.DmarragedelavoitureSwitch.Items = {'STOP', 'START'};
            app.DmarragedelavoitureSwitch.ValueChangedFcn = createCallbackFcn(app, @DmarragedelavoitureSwitchValueChanged, true);
            app.DmarragedelavoitureSwitch.Value = 'STOP';
            app.Lamp = uilamp(app.UIFigure);
            app.RadiateurLampLabel = uilabel(app.UIFigure);
            app.RadiateurLampLabel.Text = 'Radiateur';
            app.RadiateurLamp = uilamp(app.UIFigure);
            app.CarburantLampLabel = uilabel(app.UIFigure);
            app.CarburantLampLabel.Text = 'Carburant';
            app.CarburantLamp = uilamp(app.UIFigure);
            app.MoteurLampLabel = uilabel(app.UIFigure);
            app.MoteurLampLabel.Text = 'Moteur';
            app.MoteurLamp = uilamp(app.UIFigure);
            app.STOPLampLabel = uilabel(app.UIFigure);
            app.STOPLampLabel.Text = 'STOP';
            app.STOPLamp = uilamp(app.UIFigure);
            app.TableaudeBordLabel = uilabel(app.UIFigure);
            app.TableaudeBordLabel.Text = 'Tableau de Bord';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TableauBordApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end